classdef CPPapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CPPAppUIFigure               matlab.ui.Figure
        InputLabel                   matlab.ui.control.Label
        SetParameterslLabel          matlab.ui.control.Label
        SensorthicknesstSliderLabel  matlab.ui.control.Label
        SthicknessSlider             matlab.ui.control.Slider
        SensordiameterdSliderLabel   matlab.ui.control.Label
        SdiameterSlider              matlab.ui.control.Slider
        ModelradiusRSliderLabel      matlab.ui.control.Label
        MradiusSlider                matlab.ui.control.Slider
        OutputLabel                  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        SthicknessLabel              matlab.ui.control.Label
        SthicknessValLabel           matlab.ui.control.Label
        SdiameterLabel               matlab.ui.control.Label
        SdiameterValLabel            matlab.ui.control.Label
        MradiusLabel                 matlab.ui.control.Label
        MradiusValLabel              matlab.ui.control.Label
        CPPabsoluteLabel             matlab.ui.control.Label
        CPPpercentLabel              matlab.ui.control.Label
        CPPabsoluteValLabel          matlab.ui.control.Label
        CPPpercentValLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        cpp        
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            
            [thickness,diameter,radius] = getSliderValues(app);
            [CPP, CPP2, CPP3, CPPpc, xRange] = cppfunc(app,thickness,diameter,radius);
                        
            app.SthicknessValLabel.Text = num2str(round(thickness,1));
            app.SdiameterValLabel.Text = num2str(round(diameter,1));
            app.MradiusValLabel.Text = num2str(round(radius,1));
            app.CPPabsoluteValLabel.Text = num2str(round(CPP,3));
            app.CPPpercentValLabel.Text = num2str(round(CPPpc));
            
            cla(app.UIAxes)
            plot(app.UIAxes, xRange(1,:), CPP2, 'b');
            plot(app.UIAxes, log(thickness/radius), CPP, 'ro');
            title(app.UIAxes, strcat('CPP and approximation with constant \alpha = ', strcat(num2str((diameter*180)/(radius*pi)), '°')));
            
            cla(app.UIAxes2)
            plot(app.UIAxes2, xRange(2,:)*(180/pi), CPP3, 'b');
            plot(app.UIAxes2, (diameter/radius)*(180/pi), CPP, 'ro');
            title(app.UIAxes2, strcat('CPP and approximation with constant ratio t/R = ', num2str(thickness/radius)));
            
        end
        
        
        function [CPP, CPP2, CPP3, CPPpc, xRange] = cppfunc(app,thickness,diameter,radius)
            
            radiusRange = [1:1:1500];
                                 
            CPP = app.cpp(diameter/radius,thickness,radius);
            CPPpc = CPP*100-100;
            
            xRange(1,:) = log(thickness./radiusRange);
            xRange(2,:) = diameter./radiusRange;
%             CPP2 = app.cpp(diameter/radius,thickness,radiusRange(:));
            CPP2 = arrayfun(@(x) app.cpp(diameter/radius,thickness,x), radiusRange(1:end));
            CPP3 = arrayfun(@(x) app.cpp(diameter/x,thickness,radius), radiusRange(1:end));
            
        end
        
        function [thickness,diameter,radius] = getSliderValues(app)
            
            thickness = app.SthicknessSlider.Value;
            diameter = app.SdiameterSlider.Value;
            radius = app.MradiusSlider.Value;
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.cpp = @(x,y,z) (1 + (sqrt(2*(y./z)+(y./z).^2)/tan(x./2)))/(1 + (y./z));
            xlabel(app.UIAxes, 'log(t/R)');
            ylabel(app.UIAxes, 'CPP');
        
            xlabel(app.UIAxes2, 'Angle \alpha [°]');
            ylabel(app.UIAxes2, 'CPP');
            
            updatePlot(app);
            
        end

        % Value changed function: SthicknessSlider
        function SthicknessValueChanged(app, event)
            
            updatePlot(app);
            
        end

        % Value changed function: SdiameterSlider
        function SdiameterValueChanged(app, event)
            
            updatePlot(app);
            
        end

        % Value changed function: MradiusSlider
        function MradiusValueChanged(app, event)
            
            updatePlot(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CPPAppUIFigure = uifigure;
            app.CPPAppUIFigure.Name = 'CPP App';
            setAutoResize(app, app.CPPAppUIFigure, true)
            app.InputLabel = uilabel(app.CPPAppUIFigure);
            app.InputLabel.Text = '    Input';
            app.SetParameterslLabel = uilabel(app.CPPAppUIFigure);
            app.SetParameterslLabel.Text = 'Set your Parameters of Sensor and Model in mm:';
            app.SensorthicknesstSliderLabel = uilabel(app.CPPAppUIFigure);
            app.SensorthicknesstSliderLabel.Text = 'Sensorthickness t:';
            app.SthicknessSlider = uislider(app.CPPAppUIFigure);
            app.SthicknessSlider.Limits = [0.1 5];
            app.SthicknessSlider.ValueChangedFcn = createCallbackFcn(app, @SthicknessValueChanged, true);
            app.SthicknessSlider.Value = 0.1;
            app.SensordiameterdSliderLabel = uilabel(app.CPPAppUIFigure);
            app.SensordiameterdSliderLabel.Text = 'Sensordiameter d:';
            app.SdiameterSlider = uislider(app.CPPAppUIFigure);
            app.SdiameterSlider.Limits = [5 60];
            app.SdiameterSlider.ValueChangedFcn = createCallbackFcn(app, @SdiameterValueChanged, true);
            app.SdiameterSlider.Value = 5;
            app.ModelradiusRSliderLabel = uilabel(app.CPPAppUIFigure);
            app.ModelradiusRSliderLabel.Text = 'Modelradius R:';
            app.MradiusSlider = uislider(app.CPPAppUIFigure);
            app.MradiusSlider.Limits = [25 80];
            app.MradiusSlider.ValueChangedFcn = createCallbackFcn(app, @MradiusValueChanged, true);
            app.MradiusSlider.Value = 25;
            app.OutputLabel = uilabel(app.CPPAppUIFigure);
            app.OutputLabel.Text = '    Output';
            app.UIAxes = uiaxes(app.CPPAppUIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UIAxes.XLim = [-7 -2];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 8];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes2 = uiaxes(app.CPPAppUIFigure);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.UIAxes2.XLim = [0 120];
            app.UIAxes2.XLimMode = 'manual';
            app.UIAxes2.YLim = [0 8];
            app.UIAxes2.YLimMode = 'manual';
            app.SthicknessLabel = uilabel(app.CPPAppUIFigure);
            app.SthicknessLabel.Text = ' Sensorthickness [mm]: ';
            app.SthicknessValLabel = uilabel(app.CPPAppUIFigure);
            app.SthicknessValLabel.Text = '';
            app.SdiameterLabel = uilabel(app.CPPAppUIFigure);
            app.SdiameterLabel.Text = ' Sensordiameter [mm]: ';
            app.SdiameterValLabel = uilabel(app.CPPAppUIFigure);
            app.SdiameterValLabel.Text = '';
            app.MradiusLabel = uilabel(app.CPPAppUIFigure);
            app.MradiusLabel.Text = ' Modelradius [mm]: ';
            app.MradiusValLabel = uilabel(app.CPPAppUIFigure);
            app.MradiusValLabel.Text = '';
            app.CPPabsoluteLabel = uilabel(app.CPPAppUIFigure);
            app.CPPabsoluteLabel.Text = ' CPP absolute: ';
            app.CPPpercentLabel = uilabel(app.CPPAppUIFigure);
            app.CPPpercentLabel.Text = ' Error perturbation [%]:';
            app.CPPabsoluteValLabel = uilabel(app.CPPAppUIFigure);
            app.CPPabsoluteValLabel.Text = '';
            app.CPPpercentValLabel = uilabel(app.CPPAppUIFigure);
            app.CPPpercentValLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CPPapp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CPPAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CPPAppUIFigure)
        end
    end
end