classdef CPPappTEST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CPPAppUIFigure               matlab.ui.Figure
        InputLabel                   matlab.ui.control.Label
        SetParameterslLabel          matlab.ui.control.Label
        SensorthicknesstSliderLabel  matlab.ui.control.Label
        SthicknessSlider             matlab.ui.control.Slider
        SensordiameterdSliderLabel   matlab.ui.control.Label
        SdiameterSlider              matlab.ui.control.Slider
        ModelradiusRSliderLabel      matlab.ui.control.Label
        MradiusSlider                matlab.ui.control.Slider
        OutputLabel                  matlab.ui.control.Label
        SthicknessLabel              matlab.ui.control.Label
        SthicknessValLabel           matlab.ui.control.Label
        SdiameterLabel               matlab.ui.control.Label
        SdiameterValLabel            matlab.ui.control.Label
        MradiusLabel                 matlab.ui.control.Label
        MradiusValLabel              matlab.ui.control.Label
        CPPabsoluteLabel             matlab.ui.control.Label
        CPPpercentLabel              matlab.ui.control.Label
        CPPabsoluteValLabel          matlab.ui.control.Label
        CPPpercentValLabel           matlab.ui.control.Label
        CTPLabel                     matlab.ui.control.Label
        CTPValLabel                  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        CPPVisualizationTab          matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        PressureDistributionTab      matlab.ui.container.Tab
        UIAxes3                      matlab.ui.control.UIAxes
        SetPressurelLabel            matlab.ui.control.Label
        PressureSliderLabel          matlab.ui.control.Label
        PressureSlider               matlab.ui.control.Slider
    end


    properties (Access = private)
        
%         cpp = @(x,y,z,c) c*((1 + (sqrt(2*(y./z)+(y./z).^2)/tan(x./2)))/(1 + (y./z)));
        cpp = @(x,y,z,c) c*((sin(acos(z./(y+z))+0.5*x))./(sin(0.5*x)));
        ctp = @(x,y,z) ((z+y)/z)*(((x/z)+2*sqrt(1-(z/(z+y)).^2))/(2*acos(z/(z+y))+(x/z)));
        
        imageSize = 1000;
        
        youngE = 1.11e6;
        
        cppcmap = [0.200000002980232	0.800000011920929	0.501960813999176
            0.225070029497147	0.778571426868439	0.494817942380905
            0.250140070915222	0.757142841815949	0.487675100564957
            0.275210082530975	0.735714316368103	0.480532228946686
            0.300280123949051	0.714285731315613	0.473389387130737
            0.325350135564804	0.692857146263123	0.466246515512466
            0.350420176982880	0.671428561210632	0.459103673696518
            0.375490188598633	0.650000035762787	0.451960802078247
            0.400560230016708	0.628571450710297	0.444817960262299
            0.425630241632462	0.607142865657806	0.437675088644028
            0.450700283050537	0.585714280605316	0.430532246828079
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            1	1	1];
                
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            
            [thickness,diameter,radius,pressure] = getSliderValues(app);
            [CTP, CPP, CPP2, CPP3, CPPpc, xRange] = cppfunc(app,thickness,diameter,radius);
            scaleFactor = (app.imageSize/(radius+thickness+5))/2;
            [x0, y0, xSensor, ySensor, xDelta, yDelta, xBandage, yBandage] = polarfunc(app,thickness,diameter,radius,pressure,CPP);
            image = visfunc(app, diameter, thickness, radius, pressure, CPP,scaleFactor);
                                    
            app.SthicknessValLabel.Text = num2str(round(thickness,1));
            app.SdiameterValLabel.Text = num2str(round(diameter,1));
            app.MradiusValLabel.Text = num2str(round(radius,1));
            app.CTPValLabel.Text = num2str(CTP);
            app.CPPabsoluteValLabel.Text = num2str(round(CPP,3));
            app.CPPpercentValLabel.Text = num2str(round(CPPpc));
            
            cla(app.UIAxes)
            plot(app.UIAxes, xRange(1,:), CPP2, 'b');
            plot(app.UIAxes, log(thickness/radius), CPP, 'ro');
            title(app.UIAxes, strcat('CPP and trend line with constant \alpha = ', strcat(num2str((diameter*180)/(radius*pi)), '°')));
            
            cla(app.UIAxes2)
            plot(app.UIAxes2, xRange(2,:)*(180/pi), CPP3, 'b');
            plot(app.UIAxes2, (diameter/radius)*(180/pi), CPP, 'ro');
            title(app.UIAxes2, strcat('CPP and trend line with constant ratio t/R = ', num2str(thickness/radius)));
            
            cla(app.UIAxes3)
            xvalS = (scaleFactor.*xSensor + ones(1,numel(xSensor))*(size(image,1)/2));
            yvalS = (scaleFactor.*ySensor + ones(1,numel(ySensor))*(size(image,1)/2));
            xvalB = (scaleFactor.*xBandage + ones(1,numel(xBandage))*(size(image,1)/2));
            yvalB = (scaleFactor.*yBandage + ones(1,numel(yBandage))*(size(image,1)/2));
            imshow(image,'Parent', app.UIAxes3, 'Colormap', hot);
            plot(app.UIAxes3, xvalS, (ones(1,numel(yvalS))*size(image,2))-yvalS, 'LineWidth', 2);
            plot(app.UIAxes3, xvalB, (ones(1,numel(yvalB))*size(image,2))-yvalB, 'LineWidth', 2);
            
        end
        
        
        function [CTP, CPP, CPP2, CPP3, CPPpc, xRange] = cppfunc(app,thickness,diameter,radius)
            
            radiusRange = [1:1:1500];
            
            CTP = app.ctp(diameter,thickness,radius);
            CPP = app.cpp(diameter/radius,thickness,radius,CTP);
            CPPpc = CPP*100-100;
            
            xRange(1,:) = log(thickness./radiusRange);
            xRange(2,:) = diameter./radiusRange;
            %             CPP2 = app.cpp(diameter/radius,thickness,radiusRange(:));
            CPP2 = arrayfun(@(x) app.cpp(diameter/radius,thickness,x,CTP), radiusRange(1:end));
            CPP3 = arrayfun(@(x) app.cpp(diameter/x,thickness,radius,CTP), radiusRange(1:end));
            
        end
        
        function [x0, y0, xSensor, ySensor, xDelta, yDelta, xBandage, yBandage] = polarfunc(app,thickness,diameter,radius,pressure,CPP)
            
            theta0 = linspace(0,2*pi);
            thetaSensor = [linspace((pi/2)-0.5*(diameter/radius),(pi/2)+0.5*(diameter/radius)), fliplr(linspace((pi/2)-0.5*(diameter/radius),(pi/2)+0.5*(diameter/radius))), (pi/2)-0.5*(diameter/radius)];
            rho0 = ones(1,numel(theta0))*radius;
            rhoSensor = [ones(1,(numel(thetaSensor)-1)/2)*(radius+0.1), ones(1,(numel(thetaSensor-1)-1)/2)*(radius+thickness), radius+0.1];
            
            thetaDelta = [linspace(0,(pi/2)-0.5*(diameter/radius)-acos(radius/(radius+thickness))), linspace((pi/2)+0.5*(diameter/radius)+acos(radius/(radius+thickness)), (2*pi))];
            rhoDelta = ones(1,numel(thetaDelta))*(radius-10*((pressure*radius)/app.youngE));
            rhoDeltaS = ones(1,numel(thetaSensor))*(radius-10*((pressure*radius*CPP)/app.youngE));
            rhoBandage = ones(1,numel(thetaDelta))*(radius+0.2);
            rhoBandageS = ones(1,numel(thetaSensor))*(radius+thickness+0.2);
                        
            thetaDelta = cat(2,thetaDelta, thetaSensor);
            [thetaDelta, sorti] = sort(thetaDelta);
            rhoDelta = cat(2,rhoDelta, rhoDeltaS);
            rhoBandage = cat(2,rhoBandage,rhoBandageS);
            rhoDelta = rhoDelta(sorti);
            rhoBandage = rhoBandage(sorti);
            
            [x0, y0] = pol2cart(theta0, rho0);
            [xSensor, ySensor] = pol2cart(thetaSensor, rhoSensor);
            [xDelta, yDelta] = pol2cart(thetaDelta, rhoDelta);
            [xBandage, yBandage] = pol2cart(thetaDelta, rhoBandage);
                        
        end
        
        function image = visfunc(app,diameter,thickness,radius,pressure,CPP,scaleFactor)
            
            imageArray1 = ones(app.imageSize);
            imageArray = imageArray1;
                        
            imageArray(app.imageSize/2,app.imageSize/2) = 0;
            b = bwdist(imcomplement(imageArray));                        
            imageArray(b < (scaleFactor*radius)) = 0;
            circleDist = bwdist(imageArray);
            circleDist1 = circleDist;
            circleDist1(circleDist > (scaleFactor*5*((pressure*radius)/app.youngE))) = 0;
            circleDist1(circleDist < (scaleFactor*5*((pressure*radius)/app.youngE))) = (max(max(circleDist1)) - (circleDist1(circleDist < (scaleFactor*5*((pressure*radius)/app.youngE)))))/(scaleFactor*(radius - 5*((pressure*radius)/app.youngE)));
            circleDistinv = (ones(app.imageSize)*max(max(circleDist))-circleDist)./max(max(circleDist));
            circlePress = imcomplement(imageArray).*circleDist1;
                        
            polyvert1 = [size(imageArray,1)/2, size(imageArray,1)/2; tan(diameter/(2*radius))*(app.imageSize/2)+(app.imageSize/2), 0; -tan(diameter/(2*radius))*(app.imageSize/2)+(app.imageSize/2), 0];
            polyobj1 = roipoly(imageArray1, polyvert1(:,1), polyvert1(:,2));                       
            polyRest1 = immultiply(imageArray,polyobj1);
            polyDist1 = bwdist(polyRest1);
            polyDist11 = polyDist1;
            polyDist11(polyDist1 > (scaleFactor*5*((pressure*radius*CPP)/app.youngE))) = 0;
            polyDist11(polyDist1 < (scaleFactor*5*((pressure*radius*CPP)/app.youngE))) = (max(max(polyDist11)) - (polyDist11(polyDist1 < (scaleFactor*5*((pressure*radius*CPP)/app.youngE)))))/(scaleFactor*(radius - 5*((pressure*radius*CPP)/app.youngE)));
            polyDist111 = imcomplement(imageArray).*polyDist11;            

            polyDist1inv = (ones(app.imageSize)*max(max(polyDist1))-polyDist1);
            polyDist1inv(polyDist1 > (scaleFactor*20*((pressure*radius*CPP)/app.youngE))) = 0;
            polyDist1inv(polyDist1inv ~= 0 & polyDist1inv ~= max(max(polyDist1inv))) = polyDist1inv(polyDist1inv ~= 0 & polyDist1inv ~= max(max(polyDist1inv)))/(max(max(polyDist1inv))- scaleFactor*20*(radius-((pressure*radius*CPP)/app.youngE)));
            polyPress1 = imcomplement(imageArray).*polyDist1inv;
                        
            polyvert2 = [size(imageArray,1)/2, size(imageArray,1)/2; tan((diameter/(2*radius))+acos(radius/(radius+thickness)))*(app.imageSize/2)+(app.imageSize/2), 0; -tan((diameter/(2*radius))+acos(radius/(radius+thickness)))*(app.imageSize/2)+(app.imageSize/2), 0];
            polyobj2 = roipoly(imageArray1, polyvert2(:,1), polyvert2(:,2));
            polyRest2 = immultiply(imageArray,imcomplement(polyobj2));
            polyDist2 = bwdist(polyRest2);
            polyDist22 = polyDist2;
            polyDist22(polyDist2 > (scaleFactor*5*((pressure*radius)/app.youngE))) = 0;
            polyDist22(polyDist2 < (scaleFactor*5*((pressure*radius)/app.youngE))) = (max(max(polyDist22)) - (polyDist22(polyDist2 < (scaleFactor*5*((pressure*radius)/app.youngE)))))/(scaleFactor*(radius - 5*((pressure*radius)/app.youngE)));
            polyDist222 = imcomplement(imageArray).*polyDist22;
            
            polyDist2inv = (ones(app.imageSize)*max(max(polyDist2))-polyDist2)./max(max(polyDist2));
            polyPress2 = imcomplement(imageArray).*polyDist2inv;
            
            ff = pressure/(app.PressureSlider.Limits(2)*133.32);
            polyPressComb = (((polyPress1*CPP)+circlePress)*ff)/15;
            
            imagePress = ((polyDist111+polyDist222)/2) + imageArray;
            
            close all;
            figure();
            imshow(imagePress, 'Colormap', app.cppcmap);
            
            image = polyDist111;
            
        end
        
        function [thickness,diameter,radius,pressure] = getSliderValues(app)
            
            thickness = app.SthicknessSlider.Value;
            diameter = app.SdiameterSlider.Value;
            radius = app.MradiusSlider.Value;
            pressure = app.PressureSlider.Value*133.32;
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            xlabel(app.UIAxes, 'log(t/R)');
            ylabel(app.UIAxes, 'CPP');
        
            xlabel(app.UIAxes2, 'Angle \alpha [°]');
            ylabel(app.UIAxes2, 'CPP');
            
            updatePlot(app);
            
        end

        % Value changed function: SthicknessSlider
        function SthicknessValueChanged(app, event)
            
            updatePlot(app);
            
        end

        % Value changed function: SdiameterSlider
        function SdiameterValueChanged(app, event)
            
            updatePlot(app);
            
        end

        % Value changed function: MradiusSlider
        function MradiusValueChanged(app, event)
            
            updatePlot(app);
            
        end

        % Value changed function: PressureSlider
        function PressureSliderValueChanged(app, event)
            
            updatePlot(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CPPAppUIFigure = uifigure;
            app.CPPAppUIFigure.Name = 'CPP App';
            setAutoResize(app, app.CPPAppUIFigure, true)
            app.InputLabel = uilabel(app.CPPAppUIFigure);
            app.InputLabel.Text = '    Input';
            app.SetParameterslLabel = uilabel(app.CPPAppUIFigure);
            app.SetParameterslLabel.Text = 'Set your Parameters of Sensor and Model in mm:';
            app.SensorthicknesstSliderLabel = uilabel(app.CPPAppUIFigure);
            app.SensorthicknesstSliderLabel.Text = 'Sensorthickness t:';
            app.SthicknessSlider = uislider(app.CPPAppUIFigure);
            app.SthicknessSlider.Limits = [0.1 5];
            app.SthicknessSlider.ValueChangedFcn = createCallbackFcn(app, @SthicknessValueChanged, true);
            app.SthicknessSlider.Value = 0.1;
            app.SensordiameterdSliderLabel = uilabel(app.CPPAppUIFigure);
            app.SensordiameterdSliderLabel.Text = 'Sensordiameter d:';
            app.SdiameterSlider = uislider(app.CPPAppUIFigure);
            app.SdiameterSlider.Limits = [5 60];
            app.SdiameterSlider.ValueChangedFcn = createCallbackFcn(app, @SdiameterValueChanged, true);
            app.SdiameterSlider.Value = 5;
            app.ModelradiusRSliderLabel = uilabel(app.CPPAppUIFigure);
            app.ModelradiusRSliderLabel.Text = 'Modelradius R:';
            app.MradiusSlider = uislider(app.CPPAppUIFigure);
            app.MradiusSlider.Limits = [25 80];
            app.MradiusSlider.ValueChangedFcn = createCallbackFcn(app, @MradiusValueChanged, true);
            app.MradiusSlider.Value = 25;
            app.OutputLabel = uilabel(app.CPPAppUIFigure);
            app.OutputLabel.Text = '    Output';
            app.SthicknessLabel = uilabel(app.CPPAppUIFigure);
            app.SthicknessLabel.Text = ' Sensorthickness [mm]: ';
            app.SthicknessValLabel = uilabel(app.CPPAppUIFigure);
            app.SthicknessValLabel.Text = '';
            app.SdiameterLabel = uilabel(app.CPPAppUIFigure);
            app.SdiameterLabel.Text = ' Sensordiameter [mm]: ';
            app.SdiameterValLabel = uilabel(app.CPPAppUIFigure);
            app.SdiameterValLabel.Text = '';
            app.MradiusLabel = uilabel(app.CPPAppUIFigure);
            app.MradiusLabel.Text = ' Modelradius [mm]: ';
            app.MradiusValLabel = uilabel(app.CPPAppUIFigure);
            app.MradiusValLabel.Text = '';
            app.CPPabsoluteLabel = uilabel(app.CPPAppUIFigure);
            app.CPPabsoluteLabel.Text = ' CPP absolute: ';
            app.CPPpercentLabel = uilabel(app.CPPAppUIFigure);
            app.CPPpercentLabel.Text = ' Error perturbation [%]:';
            app.CPPabsoluteValLabel = uilabel(app.CPPAppUIFigure);
            app.CPPabsoluteValLabel.Text = '';
            app.CPPpercentValLabel = uilabel(app.CPPAppUIFigure);
            app.CPPpercentValLabel.Text = '';
            app.CTPLabel = uilabel(app.CPPAppUIFigure);
            app.CTPLabel.Text = ' CTP: ';
            app.CTPValLabel = uilabel(app.CPPAppUIFigure);
            app.CTPValLabel.Text = '';
            app.TabGroup = uitabgroup(app.CPPAppUIFigure);
            app.CPPVisualizationTab = uitab(app.TabGroup);
            app.CPPVisualizationTab.Title = 'CPP Visualization';
            app.CPPVisualizationTab.Units = 'pixels';
            app.UIAxes = uiaxes(app.CPPVisualizationTab);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UIAxes.XLim = [-7 -2];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 8];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes2 = uiaxes(app.CPPVisualizationTab);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.UIAxes2.XLim = [0 120];
            app.UIAxes2.XLimMode = 'manual';
            app.UIAxes2.YLim = [0 8];
            app.UIAxes2.YLimMode = 'manual';
            app.PressureDistributionTab = uitab(app.TabGroup);
            app.PressureDistributionTab.Title = 'Pressure Distribution';
            app.PressureDistributionTab.Units = 'pixels';
            app.UIAxes3 = uiaxes(app.PressureDistributionTab);
            title(app.UIAxes3, 'Pressure Influence by Sensor');
            app.SetPressurelLabel = uilabel(app.PressureDistributionTab);
            app.SetPressurelLabel.Text = 'Set your Pressure of Bandage Application in mmHg:';
            app.PressureSliderLabel = uilabel(app.PressureDistributionTab);
            app.PressureSliderLabel.Text = 'Pressure';
            app.PressureSlider = uislider(app.PressureDistributionTab);
            app.PressureSlider.ValueChangedFcn = createCallbackFcn(app, @PressureSliderValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = CPPappTEST()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CPPAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CPPAppUIFigure)
        end
    end
end